classdef PatternProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternProjectGUIUIFigure       matlab.ui.Figure
        PatternRecognitionProjectLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TrainingTab                     matlab.ui.container.Tab
        BrowseDatasetButton             matlab.ui.control.Button
        PleasewaitwhileTrainingLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ChooseanexecutiontypeButtonGroup  matlab.ui.container.ButtonGroup
        SequentialButton                matlab.ui.control.RadioButton
        ParallelButton                  matlab.ui.control.RadioButton
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        TheConsumedTimeforthisMethodisLabel  matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        ThanksforwaitingLabel           matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        FeaturestobeextractedButtonGroup  matlab.ui.container.ButtonGroup
        HOGButton                       matlab.ui.control.RadioButton
        CustomButton                    matlab.ui.control.RadioButton
        TrainButton                     matlab.ui.control.Button
        SegmentSizePanel                matlab.ui.container.Panel
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel  matlab.ui.control.Label
        ClassificationTab               matlab.ui.container.Tab
        ChooseanalgorithmButtonGroup    matlab.ui.container.ButtonGroup
        WeightedkNNButton               matlab.ui.control.RadioButton
        kSpinnerLabel                   matlab.ui.control.Label
        kSpinner                        matlab.ui.control.Spinner
        EnterweightsSEPARATEDbycommaLabel  matlab.ui.control.Label
        WeightsLabel                    matlab.ui.control.Label
        WeightsEditField                matlab.ui.control.EditField
        SVMButton                       matlab.ui.control.RadioButton
        BayesianButton                  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        BrowseImageButton               matlab.ui.control.Button
        ClassifyButton                  matlab.ui.control.Button
        ItseemstobeletterLabel          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ViewlargerButton                matlab.ui.control.Button
        EvaluationTab                   matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        BrowseTestsetButton             matlab.ui.control.Button
        EvaluateButton                  matlab.ui.control.Button
        NeuralNetworkButton             matlab.ui.control.Button
    end

    %This function made to hundle movements between the three radio buttons
    methods (Access = public)
        function RadioBtnGUI(app,kL, kS, kV, wL, wE, wV, wLL)
            app.kSpinner.Enable = kS;
            app.WeightsEditField.Enable = wE;
            app.WeightsLabel.Enable = wL;
            app.WeightsEditField.Value = wV;
            app.EnterweightsSEPARATEDbycommaLabel.Enable = wLL;
            app.kSpinnerLabel.FontColor = kL;
            app.kSpinner.Value = kV;
        end
        
        function WaitGUI(app, finishTime)
            %app.BrowseDataset.Enable = 'off'
            %Delete Thanks Label and ConsumedTime Label (if still)
            app.Lamp_2.Visible = 'off'; %Green Lamp off
            app.ThanksforwaitingLabel.Visible = 'off';
            app.TheConsumedTimeforthisMethodisLabel.Visible = 'off'
            app.sLabel.Visible = 'off';
            
            app.Lamp.Visible = 'on'; %Red Lamp on
            app.PleasewaitwhileTrainingLabel.Visible = 'on';
            app.Label.Visible = 'on';
            %...
            for i = 1:finishTime
                app.Label.Text = '';
                pause(0.5);
                app.Label.Text = '.';
                pause(0.5);
                app.Label.Text = '. .';
                pause(0.5);
                app.Label.Text = '. . .';
                pause(0.5);
            end
        end
        
        function AfterWaitingGUI(app, timeConsumed)
            app.Lamp.Visible = 'off'; %Red Lamp off
            app.PleasewaitwhileTrainingLabel.Visible = 'off';
            app.Label.Visible = 'off'; %...
            
            app.Lamp_2.Visible = 'on'; %Green Lamp on
            app.ThanksforwaitingLabel.Visible = 'on';
            app.TheConsumedTimeforthisMethodisLabel.Visible = 'on'
            app.sLabel.Visible = 'on';
            if timeConsumed < 60
                app.sLabel.Text = strcat(num2str(timeConsumed),' s');
            else
                timeConsumedMinutes = sprintf('%.2f', (timeConsumed/60)); 
                app.sLabel.Text = strcat(num2str(timeConsumedMinutes),' m');
            end
            
            %app.BrowseDataset.Enable = 'on'
            app.SaveButton.Enable = 'on'
            app.LoadButton.Enable = 'on'
            %Wait seconds then delete Thanks Label and ConsumedTime Label
            pause(10);
            app.Lamp_2.Visible = 'off'; %Green Lamp off
            app.ThanksforwaitingLabel.Visible = 'off';
            app.TheConsumedTimeforthisMethodisLabel.Visible = 'off'
            app.sLabel.Visible = 'off';
        end
    end

    methods (Access = private)

        % Button pushed function: BrowseDatasetButton
        function BrowseDatasetButtonPushed(app, event)
            folderName = uigetdir('Select a folder');
            if ~isequal(folderName,0)
                %Waiting while training
                WaitGUI(app, 2); %This function takes finishTime as the second argument
                
                %After training
                app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel.Visible = 'off';
                app.TrainButton.Enable = 'on';
                AfterWaitingGUI(app, 135.8) %This function takes timeConsumed as the second argument AS SECONDS NOT MINUTES (it converts seconds to minutes if needed)
            end
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg;*.tif;*.png;'},'Select an image');
            if ~isequal(fileName,0)
                imgPath = strcat(pathName,fileName);
                testImg = imread(imgPath);
                imshow(testImg, 'Parent', app.UIAxes);
                app.ClassifyButton.Enable = 'on';
            end
        end

        % Selection changed function: ChooseanalgorithmButtonGroup
        function ChooseanalgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseanalgorithmButtonGroup.SelectedObject;
            
            if app.WeightedkNNButton.Value == true
                RadioBtnGUI(app,[0 0 0],'on',0,'on','on','0','on');
            end
            
            if app.BayesianButton.Value == true
                RadioBtnGUI(app,[0.6667 0.6667 0.6667],'off',0,'off','off','0','off');
            end
            
            if app.SVMButton.Value == true
                RadioBtnGUI(app,[0.6667 0.6667 0.6667],'off',0,'off','off','0','off');
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            %app.PleasewaitwhileTrainingLabel.Visible = 'off';
            %app.Label.Visible = 'off';
            
            %Taking the text as a string then separating to chars (cell of chars)
            weightsStr = strsplit(app.WeightsEditField.Value,',');
            
            %Getting size of the previous cell
            [~,n] = size(weightsStr);
            
            %Converting every element in the cell to number
            for i = 1:n
                weightsNum{i} = str2num(weightsStr{i});
            end
            app.ItseemstobeletterLabel.Visible = 'on';
            app.XLabel.Visible = 'on';
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %load(filename);
            [fName, pName] = uigetfile({'*.mat;'},'Select the trained file');
            if ~isequal(fName,0)
                fPath = strcat(pName,fName);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %save(filename);
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            app.UITable.Data = [1 2 3 4 5];
        end

        % Button pushed function: BrowseTestsetButton
        function BrowseTestsetButtonPushed(app, event)
            folderName = uigetdir('Select a folder');
            if ~isequal(folderName,0)
                app.EvaluateButton.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternProjectGUIUIFigure = uifigure;
            app.PatternProjectGUIUIFigure.Name = 'PatternProjectGUI';
            setAutoResize(app, app.PatternProjectGUIUIFigure, false)
            app.PatternRecognitionProjectLabel = uilabel(app.PatternProjectGUIUIFigure);
            app.PatternRecognitionProjectLabel.Text = 'Pattern Recognition Project';
            app.TabGroup = uitabgroup(app.PatternProjectGUIUIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.Units = 'pixels';
            app.BrowseDatasetButton = uibutton(app.TrainingTab, 'push');
            app.BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDatasetButtonPushed, true);
            app.BrowseDatasetButton.Text = 'Browse [Dataset]';
            app.PleasewaitwhileTrainingLabel = uilabel(app.TrainingTab);
            app.PleasewaitwhileTrainingLabel.Visible = 'off';
            app.PleasewaitwhileTrainingLabel.Text = 'Please wait while Training';
            app.Label = uilabel(app.TrainingTab);
            app.Label.Visible = 'off';
            app.Label.Text = '...';
            app.ChooseanexecutiontypeButtonGroup = uibuttongroup(app.TrainingTab);
            app.ChooseanexecutiontypeButtonGroup.Title = 'Choose an execution type :';
            app.SequentialButton = uiradiobutton(app.ChooseanexecutiontypeButtonGroup);
            app.SequentialButton.Text = 'Sequential';
            app.SequentialButton.Value = true;
            app.ParallelButton = uiradiobutton(app.ChooseanexecutiontypeButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.SaveButton = uibutton(app.TrainingTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.TrainingTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.Lamp = uilamp(app.TrainingTab);
            app.Lamp.Visible = 'off';
            app.TheConsumedTimeforthisMethodisLabel = uilabel(app.TrainingTab);
            app.TheConsumedTimeforthisMethodisLabel.Visible = 'off';
            app.TheConsumedTimeforthisMethodisLabel.Text = 'The Consumed Time for this Method is : ';
            app.Lamp_2 = uilamp(app.TrainingTab);
            app.Lamp_2.Visible = 'off';
            app.ThanksforwaitingLabel = uilabel(app.TrainingTab);
            app.ThanksforwaitingLabel.Visible = 'off';
            app.ThanksforwaitingLabel.Text = 'Thanks for waiting :)';
            app.sLabel = uilabel(app.TrainingTab);
            app.sLabel.Visible = 'off';
            app.sLabel.Text = '30 s';
            app.FeaturestobeextractedButtonGroup = uibuttongroup(app.TrainingTab);
            app.FeaturestobeextractedButtonGroup.Title = 'Features to be extracted :';
            app.HOGButton = uiradiobutton(app.FeaturestobeextractedButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.HOGButton.Value = true;
            app.CustomButton = uiradiobutton(app.FeaturestobeextractedButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.TrainButton = uibutton(app.TrainingTab, 'push');
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.SegmentSizePanel = uipanel(app.TrainingTab);
            app.SegmentSizePanel.Title = 'Segment Size :';
            app.WidthEditFieldLabel = uilabel(app.SegmentSizePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.SegmentSizePanel, 'numeric');
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.Value = 8;
            app.HeightEditFieldLabel = uilabel(app.SegmentSizePanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.SegmentSizePanel, 'numeric');
            app.HeightEditField.Limits = [1 Inf];
            app.HeightEditField.Value = 8;
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel = uilabel(app.TrainingTab);
            app.PleasefirstyouMUSTgotoTrainingTabtoBrowsetheDatLabel.Text = {'Please, First you MUST go '; 'to Training Tab to Browse '; 'the Dataset, then Choose'; 'a Method to train'};
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.ClassificationTab.Units = 'pixels';
            app.ChooseanalgorithmButtonGroup = uibuttongroup(app.ClassificationTab);
            app.ChooseanalgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseanalgorithmButtonGroupSelectionChanged, true);
            app.ChooseanalgorithmButtonGroup.Title = 'Choose an algorithm :';
            app.WeightedkNNButton = uiradiobutton(app.ChooseanalgorithmButtonGroup);
            app.WeightedkNNButton.Text = 'Weighted kNN';
            app.WeightedkNNButton.Value = true;
            app.kSpinnerLabel = uilabel(app.ChooseanalgorithmButtonGroup);
            app.kSpinnerLabel.Text = 'k : ';
            app.kSpinner = uispinner(app.ChooseanalgorithmButtonGroup);
            app.kSpinner.Limits = [0 9];
            app.kSpinner.ValueDisplayFormat = '%.0f';
            app.EnterweightsSEPARATEDbycommaLabel = uilabel(app.ChooseanalgorithmButtonGroup);
            app.EnterweightsSEPARATEDbycommaLabel.Text = '**Enter weights SEPARATED by comma '',''';
            app.WeightsLabel = uilabel(app.ChooseanalgorithmButtonGroup);
            app.WeightsLabel.Text = 'Weights **';
            app.WeightsEditField = uieditfield(app.ChooseanalgorithmButtonGroup, 'text');
            app.WeightsEditField.Value = '0';
            app.SVMButton = uiradiobutton(app.ChooseanalgorithmButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.BayesianButton = uiradiobutton(app.ChooseanalgorithmButtonGroup);
            app.BayesianButton.Text = 'Bayesian';
            app.UIAxes = uiaxes(app.ClassificationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.BrowseImageButton = uibutton(app.ClassificationTab, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.ClassifyButton = uibutton(app.ClassificationTab, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Enable = 'off';
            app.ClassifyButton.Text = 'Classify';
            app.ItseemstobeletterLabel = uilabel(app.ClassificationTab);
            app.ItseemstobeletterLabel.Visible = 'off';
            app.ItseemstobeletterLabel.Text = 'It seems to be      letter';
            app.XLabel = uilabel(app.ClassificationTab);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X';
            app.ViewlargerButton = uibutton(app.ClassificationTab, 'push');
            app.ViewlargerButton.Text = 'View larger';
            app.EvaluationTab = uitab(app.TabGroup);
            app.EvaluationTab.Title = 'Evaluation';
            app.EvaluationTab.Units = 'pixels';
            app.UITable = uitable(app.EvaluationTab);
            app.UITable.ColumnName = {'Right'; 'Wrong'; 'Error Ratio'; 'Accuracy'; 'Ellapsed Time'};
            app.UITable.RowName = {};
            app.BrowseTestsetButton = uibutton(app.EvaluationTab, 'push');
            app.BrowseTestsetButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTestsetButtonPushed, true);
            app.BrowseTestsetButton.Text = 'Browse Testset';
            app.EvaluateButton = uibutton(app.EvaluationTab, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Enable = 'off';
            app.EvaluateButton.Text = 'Evaluate';
            app.NeuralNetworkButton = uibutton(app.EvaluationTab, 'push');
            app.NeuralNetworkButton.Text = 'Neural Network';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PatternProjectGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternProjectGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternProjectGUIUIFigure)
        end
    end
end