classdef SignalProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalProjectGUIUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Question1ContinuousTab          matlab.ui.container.Tab
        UIAxes_Continuous_OriginalSignal  matlab.ui.control.UIAxes
        UIAxes_Continuous_ChangedAmplitude  matlab.ui.control.UIAxes
        UIAxes_Continuous_ChangedPhase  matlab.ui.control.UIAxes
        UIAxes_Continuous_ChangedFrequency  matlab.ui.control.UIAxes
        Q1_Button_DrawContinuous        matlab.ui.control.Button
        ContinuousFormula_Panel         matlab.ui.container.Panel
        tLabel_2                        matlab.ui.control.Label
        plusORminusContinuous_DropDown  matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        AEditFieldLabel                 matlab.ui.control.Label
        AmplitudeContinuous_EditField   matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        FrequencyContinuousEditField    matlab.ui.control.NumericEditField
        Label_25                        matlab.ui.control.Label
        PhaseContinuous_EditField       matlab.ui.control.NumericEditField
        sincosContinuous_DropDown       matlab.ui.control.DropDown
        sincosDropDownLabel             matlab.ui.control.Label
        tLabel_3                        matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        tEnd_EditField                  matlab.ui.control.NumericEditField
        Label_48                        matlab.ui.control.Label
        tStart_EditField                matlab.ui.control.NumericEditField
        NpointsbetweenStartandEndIf0thenN4StartEnd1Label  matlab.ui.control.Label
        NpointsbetweenStartandEndIf0thenN4StartEnd1EditField  matlab.ui.control.NumericEditField
        sincostContinuousOriginal_Label  matlab.ui.control.Label
        sincostContinuousAmplitude_Label  matlab.ui.control.Label
        sincostContinuousPhase_Label    matlab.ui.control.Label
        sincostContinuousFraquency_Label  matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Question2DiscreteTab            matlab.ui.container.Tab
        UIAxes_Discrete_OriginalSignal  matlab.ui.control.UIAxes
        UIAxes_Discrete_ChangedPhase    matlab.ui.control.UIAxes
        UIAxes_Discrete_ChangedFrequency  matlab.ui.control.UIAxes
        Q2_Button_DrawDisctrete         matlab.ui.control.Button
        DiscreteFormula_Panel           matlab.ui.container.Panel
        nLabel_2                        matlab.ui.control.Label
        plusORminusDiscrete_DropDown    matlab.ui.control.DropDown
        Label_36                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        AEditField_2Label               matlab.ui.control.Label
        AmplitudeDiscrete_EditField     matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        FrequencyDiscrete_EditField     matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        PhaseDiscrete_EditField         matlab.ui.control.NumericEditField
        sincosDiscrete_DropDown         matlab.ui.control.DropDown
        sincosDropDown_2Label           matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        nEnd_EditField                  matlab.ui.control.NumericEditField
        Label_47                        matlab.ui.control.Label
        nStart_EditField                matlab.ui.control.NumericEditField
        nLabel_3                        matlab.ui.control.Label
        sincostDiscreteOriginal_Label   matlab.ui.control.Label
        sincostDiscreteAmplitude_Label  matlab.ui.control.Label
        sincostDiscretePhase_Label      matlab.ui.control.Label
        sincostDiscreteFraquency_Label  matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        UIAxes_Discrete_ChangedAmplitude  matlab.ui.control.UIAxes
        NpointsbetweenStartandEndIf0thenNStartEnd1Label  matlab.ui.control.Label
        NpointsbetweenStartandEndIf0thenNStartEnd1EditField  matlab.ui.control.NumericEditField
        Question37Tab                   matlab.ui.container.Tab
        Panel_Question_7                matlab.ui.container.Panel
        powern_Label                    matlab.ui.control.Label
        xn14_Label                      matlab.ui.control.Label
        Q7_Result_Label                 matlab.ui.control.Label
        Q7_Label                        matlab.ui.control.Label
        Q7_ZTransform_Button            matlab.ui.control.Button
        Panel_Question_6                matlab.ui.container.Panel
        hn_Label                        matlab.ui.control.Label
        xn_Label                        matlab.ui.control.Label
        yn_Label                        matlab.ui.control.Label
        Q6_Result_Label                 matlab.ui.control.Label
        hn_EditField                    matlab.ui.control.EditField
        xn_EditField                    matlab.ui.control.EditField
        Q6_Convolution_Button           matlab.ui.control.Button
        EntervalidNUMBERSSEPARATEDeg123or123or123Label  matlab.ui.control.Label
        Q6_Label                        matlab.ui.control.Label
        Panel_Question_3                matlab.ui.container.Panel
        kLabel                          matlab.ui.control.Label
        Braces                          matlab.ui.control.Label
        Label_Sigma_Symbol              matlab.ui.control.Label
        Q3_Result_Label                 matlab.ui.control.Label
        Q3_Summations_Button            matlab.ui.control.Button
        kLabel_2                        matlab.ui.control.Label
        kStart_EditField                matlab.ui.control.NumericEditField
        Value_EditField                 matlab.ui.control.NumericEditField
        kEnd_EditField                  matlab.ui.control.NumericEditField
        Q3_Label                        matlab.ui.control.Label
        abLabel                         matlab.ui.control.Label
        Panel_Question_4                matlab.ui.container.Panel
        t_Label                         matlab.ui.control.Label
        exp_Label                       matlab.ui.control.Label
        IntSymbol_Label                 matlab.ui.control.Label
        Q4_Result_Label                 matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        dt_Label                        matlab.ui.control.Label
        Q4_Label                        matlab.ui.control.Label
        Q4_Integration_Button           matlab.ui.control.Button
        Panel_Question_5                matlab.ui.container.Panel
        power3_Label                    matlab.ui.control.Label
        yx_Label                        matlab.ui.control.Label
        dy_Label                        matlab.ui.control.Label
        dx_Label                        matlab.ui.control.Label
        over_Label                      matlab.ui.control.Label
        Q5_Result_Label                 matlab.ui.control.Label
        Q5_Label                        matlab.ui.control.Label
        Q5_Differentiation_Button       matlab.ui.control.Button
        Label_40                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        Label_42                        matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        Question8FourierTransformTab    matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        Q8_Button                       matlab.ui.control.Button
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        SignalsProjectLabel             matlab.ui.control.Label
    end


    methods (Access = public)
    
        function y = Formula(app,tORn,A,sinORcos,freq,plusORminus,phase)
            if sinORcos == 'sin'
                if plusORminus == '+'
                    y = A * sin ((freq * tORn) + phase); %Formula : A sin(𝟂(tORn) + 𝞱)
                else %plusORminus = '-'
                    y = A * sin ((freq * tORn) - phase); %Formula : A sin(𝟂(tORn) - 𝞱)
                end
                
            else %sinORcos = 'cos'
                if plusORminus == '+'
                    y = A * cos ((freq * tORn) + phase); %Formula : A cos(𝟂(tORn) + 𝞱)
                else %op = '-'
                    y = A * cos ((freq * tORn) - phase); %Formula : A cos(𝟂(tORn) - 𝞱)
                end
            end
        end


        function FormulaLabel(app, editFieldValue, labelText, labelTextIfZero, labelTextIfOne, labelTextIfNotZeroNotOne)
            if isequal(editFieldValue, 0)
                labelText.Text = labelTextIfZero;
                
            elseif isequal(editFieldValue, 1)
                labelText.Text = labelTextIfOne;
                
            else 
                labelText.Text = labelTextIfNotZeroNotOne;
            end 
        end
        
        function output = isIntORfloat(app, num)
            fractionPart = mod(double(num),1);
            if isequal(fractionPart, 0)
                output = int8(num);
            else
                output = sprintf('%.2f', num);
            end
        end 
    end


    methods (Access = private)

        % Button pushed function: Q1_Button_DrawContinuous
        function ButtonPushed_Q1_DrawContinuous(app, event)
            amplitude = app.AmplitudeContinuous_EditField.Value;
            sinORcos = app.sincosContinuous_DropDown.Value;
            plusORminus = app.plusORminusContinuous_DropDown.Value;
            phase = app.PhaseContinuous_EditField.Value;
            frequency = app.FrequencyContinuousEditField.Value;
            
            tStart = app.tStart_EditField.Value;
            tEnd = app.tEnd_EditField.Value;
            tBetween = app.NpointsbetweenStartandEndIf0thenN4StartEnd1EditField.Value;
            if isequal(tBetween,0)
                tBetween = 4*(abs(tEnd)+abs(tStart)+1);
            end
            
            if tStart >= tEnd
                uialert(app.SignalProjectGUIUIFigure,'tEnd MUST be GREATER than tStart ! ','Invalid Value');
            else
                t = linspace(tStart,tEnd,tBetween);
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Original Signal
                y = Formula(app ,  t  ,     1    ,sinORcos ,     1    ,plusORminus ,  0  );    % y = x(t) = (sin|cos)(t)
                plot(app.UIAxes_Continuous_OriginalSignal,t,y);
                %plot(app.UIAxes_Continuous_OriginalSignal,t,y,'-o','MarkerIndices',1:length(y))
                app.sincostContinuousOriginal_Label.Text = strcat(sinORcos,'(t)');
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Changed Amplitude (A)
                y = Formula(app ,  t  ,amplitude ,sinORcos ,    1     ,plusORminus ,  0  );    % y = x(t) = A*(sin|cos)(t)
                plot(app.UIAxes_Continuous_ChangedAmplitude,t,y);
                %plot(app.UIAxes_Continuous_ChangedAmplitude,t,y,'-o','MarkerIndices',1:length(y))
                FormulaLabel(app ,amplitude, app.sincostContinuousAmplitude_Label, 'Zero', strcat(sinORcos,'(t)'), strcat(num2str(amplitude),{' '},sinORcos,'(t)'));
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Changed Phase (𝞱)
                y = Formula(app ,  t  ,    1     ,sinORcos ,    1     ,plusORminus ,phase);    % y = x(t) = (sin|cos)(t(+|-)𝞱)
                plot(app.UIAxes_Continuous_ChangedPhase,t,y);
                %plot(app.UIAxes_Continuous_ChangedPhase,t,y,'-o','MarkerIndices',1:length(y))
                FormulaLabel(app, phase, app.sincostContinuousPhase_Label, strcat(sinORcos,'(t)'), strcat(sinORcos,'(t',plusORminus,'1)'), strcat(sinORcos,'(t',plusORminus,num2str(phase),')'));
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Changed Frequency (𝟂)
                y = Formula(app ,  t  ,     1    ,sinORcos ,frequency ,plusORminus ,  0  );    % y = x(t) = (sin|cos)(𝟂t)
                plot(app.UIAxes_Continuous_ChangedFrequency,t,y);
                %plot(app.UIAxes_Continuous_ChangedFrequency,t,y,'-o','MarkerIndices',1:length(y))
                FormulaLabel(app, frequency, app.sincostContinuousFraquency_Label, strcat(sinORcos,'(0) =',{' '} ,int2str(int8(isequal(sinORcos,'cos')))), strcat(sinORcos,'(t)'), strcat(sinORcos,'(',num2str(frequency),'t)'));
            end
        end

        % Button pushed function: Q2_Button_DrawDisctrete
        function ButtonPushed_Q2_DrawDisctrete(app, event)
            amplitude = app.AmplitudeDiscrete_EditField.Value;
            sinORcos = app.sincosDiscrete_DropDown.Value;
            plusORminus = app.plusORminusDiscrete_DropDown.Value;
            phase = app.PhaseDiscrete_EditField.Value;
            frequency = app.FrequencyDiscrete_EditField.Value;
            
            nStart = app.nStart_EditField.Value;
            nEnd = app.nEnd_EditField.Value;
            nBetween = app.NpointsbetweenStartandEndIf0thenNStartEnd1EditField.Value;
            if isequal(nBetween,0)
                nBetween = abs(nEnd)+abs(nStart)+1;
            end
            
            if nStart >= nEnd
                uialert(app.SignalProjectGUIUIFigure,'nEnd MUST be GREATER than nStart ! ','Invalid Value');
            else
                n = linspace(nStart,nEnd,nBetween);
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Original Signal
                y = Formula(app ,  n  ,     1    ,sinORcos ,     1    ,plusORminus ,  0  );    % y = x[n] = (sin|cos)(n)
                stem(app.UIAxes_Discrete_OriginalSignal,n,y);
                app.sincostDiscreteOriginal_Label.Text = strcat(sinORcos,'(n)');
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Changed amplitude (A)
                y = Formula(app ,  n  ,amplitude ,sinORcos ,     1    ,plusORminus ,  0  );    % y = x[n] = A*(sin|cos)(n)
                stem(app.UIAxes_Discrete_ChangedAmplitude,n,y);
                FormulaLabel(app, amplitude, app.sincostDiscreteAmplitude_Label, 'Zero', strcat(sinORcos,'(n)'), strcat(num2str(amplitude),{' '},sinORcos,'(n)'));
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Changed Phase (𝞱)
                y = Formula(app ,  n  ,     1    ,sinORcos ,     1    ,plusORminus ,phase);    % y = x[n] = (sin|cos)(n(+|-)𝞱)
                stem(app.UIAxes_Discrete_ChangedPhase,n,y);
                FormulaLabel(app, phase, app.sincostDiscretePhase_Label, strcat(sinORcos,'(n)'), strcat(sinORcos,'(n',plusORminus,'1)'), strcat(sinORcos,'(n',plusORminus,num2str(phase),')'));
                
                %   Formula(app ,tORn ,amplitude ,sinORcos ,frequency ,plusORminus ,phase)     % Changed Frequency (𝟂)
                y = Formula(app ,  n  ,    1     ,sinORcos ,frequency ,plusORminus ,  0  );    % y = x[n] = (sin|cos)(𝟂n)
                stem(app.UIAxes_Discrete_ChangedFrequency,n,y);
                FormulaLabel(app, frequency, app.sincostDiscreteFraquency_Label, strcat(sinORcos,'(0) =',{' '} ,int2str(int8(isequal(sinORcos,'cos')))), strcat(sinORcos,'(n)'), strcat(sinORcos,'(',num2str(frequency),'n)'));
            end
        end

        % Button pushed function: Q3_Summations_Button
        function ButtonPushed_Q3_Summations(app, event)
            syms k;
            kStart = app.kStart_EditField.Value;
            kEnd = app.kEnd_EditField.Value;
            value = app.Value_EditField.Value;
            if kStart < kEnd
                result = symsum(value^k,kStart,kEnd);
                app.Q3_Result_Label.Text = num2str(isIntORfloat(app, result));
            else
                uialert(app.SignalProjectGUIUIFigure,'kEnd MUST be GREATER than kStart ! ','Invalid Value');
            end
        end

        % Button pushed function: Q4_Integration_Button
        function ButtonPushed_Q4_Integration(app, event)
            syms t;
            result = int(exp(t),-Inf,0);
            app.Q4_Result_Label.Text = int2str(result);
        end

        % Button pushed function: Q5_Differentiation_Button
        function ButtonPushed_Q5_Differentiation(app, event)
            syms x;
            y = x^3;
            result = diff(y);
            app.Q5_Result_Label.Text = char(result);
        end

        % Button pushed function: Q6_Convolution_Button
        function ButtonPushed_Q6_Convolution(app, event)
            h = str2num(app.hn_EditField.Value);
            x = str2num(app.xn_EditField.Value);
            
            if ~isempty(x) && ~isempty(h)
                result = conv(h,x);
                app.Q6_Result_Label.Text = mat2str(result);
            else
                msg = sprintf('Enter valid NUMBERS SEPARATED\n(e.g. 1,2,3 or 1 2 3 or [1 2 3])');
                uialert(app.SignalProjectGUIUIFigure,msg,'Invalid Value');
                app.Q6_Result_Label.Text = '........';
            end
        end

        % Button pushed function: Q7_ZTransform_Button
        function ButtonPushed_Q7_ZTransform(app, event)
            syms n;
            result = ztrans((1/4)^n);
            app.Q7_Result_Label.Text = char(result);
        end

        % Button pushed function: Q8_Button
        function ButtonPushed_Q8_FourierTransform(app, event)
            Fs = 1000;            % Sampling frequency                    
            T = 1/Fs;             % Sampling period       
            L = 1500;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            
            %Form a signal containing a 50 Hz sinusoid of amplitude 0.7 and a 120 Hz sinusoid of amplitude 1.
            S = 0.7*sin(2*pi*50*t) + sin(2*pi*120*t);
            
            %Corrupt the signal with zero-mean white noise with a variance of 4.
            X = S + 2*randn(size(t));
            
            %Plot the noisy signal in the time domain. It is difficult to identify the frequency components by looking at the signal X(t).
            plot(app.UIAxes5_2,1000*t(1:50),X(1:50)) %t,X must be the same length AND 50 is a part of the length of signal AND 1000 for milliseconds
            title(app.UIAxes5_2,'Signal Corrupted with Zero-Mean Random Noise')
            xlabel(app.UIAxes5_2,'t (milliseconds)')
            ylabel(app.UIAxes5_2,'X(t)')
            
            Y = fft(X);
            
            %Compute the two-sided spectrum P2. Then compute the single-sided spectrum P1 based on P2 and the even-valued signal length L.
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            %Define the frequency domain f and plot the single-sided amplitude spectrum P1. The amplitudes are not exactly at 0.7 and 1, as expected, because of the added noise. On average, longer signals produce better frequency approximations.
            f = Fs*(0:(L/2))/L;
            plot(app.UIAxes5,f,P1) 
            title(app.UIAxes5,'Single-Sided Amplitude Spectrum of X(t)')
            xlabel(app.UIAxes5,'f (Hz)')
            ylabel(app.UIAxes5,'|P1(f)|')
            
            %Now, take the Fourier transform of the original, uncorrupted signal and retrieve the exact amplitudes, 0.7 and 1.0.
            Y = fft(S);    %returns complex double
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            plot(app.UIAxes6,f,P1)
            title(app.UIAxes6,'Single-Sided Amplitude Spectrum of S(t)')
            xlabel(app.UIAxes6,'f (Hz)')
            ylabel(app.UIAxes6,'|P1(f)|')
            % ref https://www.mathworks.com/help/matlab/ref/fft.html
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalProjectGUIUIFigure = uifigure;
            app.SignalProjectGUIUIFigure.Name = 'SignalProjectGUI';
            setAutoResize(app, app.SignalProjectGUIUIFigure, true)
            app.TabGroup = uitabgroup(app.SignalProjectGUIUIFigure);
            app.Question1ContinuousTab = uitab(app.TabGroup);
            app.Question1ContinuousTab.Title = 'Question 1 ( Continuous )';
            app.Question1ContinuousTab.Units = 'pixels';
            app.UIAxes_Continuous_OriginalSignal = uiaxes(app.Question1ContinuousTab);
            title(app.UIAxes_Continuous_OriginalSignal, 'Original Signal');
            xlabel(app.UIAxes_Continuous_OriginalSignal, 't');
            ylabel(app.UIAxes_Continuous_OriginalSignal, 'x(t)');
            app.UIAxes_Continuous_OriginalSignal.XAxisLocation = 'origin';
            app.UIAxes_Continuous_OriginalSignal.YAxisLocation = 'origin';
            app.UIAxes_Continuous_ChangedAmplitude = uiaxes(app.Question1ContinuousTab);
            title(app.UIAxes_Continuous_ChangedAmplitude, 'Changed Amplitude (A)');
            xlabel(app.UIAxes_Continuous_ChangedAmplitude, 't');
            ylabel(app.UIAxes_Continuous_ChangedAmplitude, 'x(t)');
            app.UIAxes_Continuous_ChangedAmplitude.XAxisLocation = 'origin';
            app.UIAxes_Continuous_ChangedAmplitude.YAxisLocation = 'origin';
            app.UIAxes_Continuous_ChangedPhase = uiaxes(app.Question1ContinuousTab);
            title(app.UIAxes_Continuous_ChangedPhase, 'Changed Phase (𝞱) ');
            xlabel(app.UIAxes_Continuous_ChangedPhase, 't');
            ylabel(app.UIAxes_Continuous_ChangedPhase, 'x(t)');
            app.UIAxes_Continuous_ChangedPhase.XAxisLocation = 'origin';
            app.UIAxes_Continuous_ChangedPhase.YAxisLocation = 'origin';
            app.UIAxes_Continuous_ChangedFrequency = uiaxes(app.Question1ContinuousTab);
            title(app.UIAxes_Continuous_ChangedFrequency, 'Changed Frequency (𝟂)');
            xlabel(app.UIAxes_Continuous_ChangedFrequency, 't');
            ylabel(app.UIAxes_Continuous_ChangedFrequency, 'x(t)');
            app.UIAxes_Continuous_ChangedFrequency.XAxisLocation = 'origin';
            app.UIAxes_Continuous_ChangedFrequency.YAxisLocation = 'origin';
            app.Q1_Button_DrawContinuous = uibutton(app.Question1ContinuousTab, 'push');
            app.Q1_Button_DrawContinuous.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q1_DrawContinuous, true);
            app.Q1_Button_DrawContinuous.Text = 'Draw';
            app.ContinuousFormula_Panel = uipanel(app.Question1ContinuousTab);
            app.ContinuousFormula_Panel.Title = 'Formula :';
            app.tLabel_2 = uilabel(app.ContinuousFormula_Panel);
            app.tLabel_2.Text = 't';
            app.plusORminusContinuous_DropDown = uidropdown(app.ContinuousFormula_Panel);
            app.plusORminusContinuous_DropDown.Items = {'+', '-'};
            app.plusORminusContinuous_DropDown.Value = '+';
            app.Label_26 = uilabel(app.ContinuousFormula_Panel);
            app.Label_26.Text = '(';
            app.Label_27 = uilabel(app.ContinuousFormula_Panel);
            app.Label_27.Text = ')    ,';
            app.Label_28 = uilabel(app.ContinuousFormula_Panel);
            app.Label_28.Text = '+';
            app.Label_29 = uilabel(app.ContinuousFormula_Panel);
            app.Label_29.Text = '_';
            app.AEditFieldLabel = uilabel(app.ContinuousFormula_Panel);
            app.AEditFieldLabel.Text = 'A';
            app.AmplitudeContinuous_EditField = uieditfield(app.ContinuousFormula_Panel, 'numeric');
            app.AmplitudeContinuous_EditField.Value = 1;
            app.EditField_5Label = uilabel(app.ContinuousFormula_Panel);
            app.EditField_5Label.Text = '𝟂';
            app.FrequencyContinuousEditField = uieditfield(app.ContinuousFormula_Panel, 'numeric');
            app.FrequencyContinuousEditField.Value = 1;
            app.Label_25 = uilabel(app.ContinuousFormula_Panel);
            app.Label_25.Text = '𝞱';
            app.PhaseContinuous_EditField = uieditfield(app.ContinuousFormula_Panel, 'numeric');
            app.sincosContinuous_DropDown = uidropdown(app.ContinuousFormula_Panel);
            app.sincosContinuous_DropDown.Items = {'sin', 'cos'};
            app.sincosContinuous_DropDown.Value = 'sin';
            app.sincosDropDownLabel = uilabel(app.ContinuousFormula_Panel);
            app.sincosDropDownLabel.Text = 'sin || cos';
            app.tLabel_3 = uilabel(app.ContinuousFormula_Panel);
            app.tLabel_3.Text = 't';
            app.Label_45 = uilabel(app.ContinuousFormula_Panel);
            app.Label_45.Text = '≤';
            app.tEnd_EditField = uieditfield(app.ContinuousFormula_Panel, 'numeric');
            app.tEnd_EditField.Limits = [-1000 1000];
            app.tEnd_EditField.Value = 10;
            app.Label_48 = uilabel(app.ContinuousFormula_Panel);
            app.Label_48.Text = '≤';
            app.tStart_EditField = uieditfield(app.ContinuousFormula_Panel, 'numeric');
            app.tStart_EditField.Limits = [-1000 1000];
            app.tStart_EditField.Value = -10;
            app.NpointsbetweenStartandEndIf0thenN4StartEnd1Label = uilabel(app.ContinuousFormula_Panel);
            app.NpointsbetweenStartandEndIf0thenN4StartEnd1Label.Text = {' N points between Start and End:'; ''; ' (If 0 then N = 4( |Start |+|End|+1)) '};
            app.NpointsbetweenStartandEndIf0thenN4StartEnd1EditField = uieditfield(app.ContinuousFormula_Panel, 'numeric');
            app.NpointsbetweenStartandEndIf0thenN4StartEnd1EditField.Limits = [0 10000];
            app.sincostContinuousOriginal_Label = uilabel(app.Question1ContinuousTab);
            app.sincostContinuousOriginal_Label.Text = '(sin | cos)(t)';
            app.sincostContinuousAmplitude_Label = uilabel(app.Question1ContinuousTab);
            app.sincostContinuousAmplitude_Label.Text = 'A (sin | cos)(t)';
            app.sincostContinuousPhase_Label = uilabel(app.Question1ContinuousTab);
            app.sincostContinuousPhase_Label.Text = '(sin|cos)(t(+|-)𝞱)';
            app.sincostContinuousFraquency_Label = uilabel(app.Question1ContinuousTab);
            app.sincostContinuousFraquency_Label.Text = '(sin | cos)(𝟂t)';
            app.Label_30 = uilabel(app.Question1ContinuousTab);
            app.Label_30.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_31 = uilabel(app.Question1ContinuousTab);
            app.Label_31.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_32 = uilabel(app.Question1ContinuousTab);
            app.Label_32.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Question2DiscreteTab = uitab(app.TabGroup);
            app.Question2DiscreteTab.Title = 'Question 2 ( Discrete )';
            app.Question2DiscreteTab.Units = 'pixels';
            app.UIAxes_Discrete_OriginalSignal = uiaxes(app.Question2DiscreteTab);
            title(app.UIAxes_Discrete_OriginalSignal, 'Original Signal');
            xlabel(app.UIAxes_Discrete_OriginalSignal, 'n');
            ylabel(app.UIAxes_Discrete_OriginalSignal, 'x[n]');
            app.UIAxes_Discrete_OriginalSignal.XAxisLocation = 'origin';
            app.UIAxes_Discrete_OriginalSignal.YAxisLocation = 'origin';
            app.UIAxes_Discrete_ChangedPhase = uiaxes(app.Question2DiscreteTab);
            title(app.UIAxes_Discrete_ChangedPhase, 'Changed Phase (𝞱) ');
            xlabel(app.UIAxes_Discrete_ChangedPhase, 'n');
            ylabel(app.UIAxes_Discrete_ChangedPhase, 'x[n]');
            app.UIAxes_Discrete_ChangedPhase.XAxisLocation = 'origin';
            app.UIAxes_Discrete_ChangedPhase.YAxisLocation = 'origin';
            app.UIAxes_Discrete_ChangedFrequency = uiaxes(app.Question2DiscreteTab);
            title(app.UIAxes_Discrete_ChangedFrequency, 'Changed Frequency (𝟂)');
            xlabel(app.UIAxes_Discrete_ChangedFrequency, 'n');
            ylabel(app.UIAxes_Discrete_ChangedFrequency, 'x[n]');
            app.UIAxes_Discrete_ChangedFrequency.XAxisLocation = 'origin';
            app.UIAxes_Discrete_ChangedFrequency.YAxisLocation = 'origin';
            app.Q2_Button_DrawDisctrete = uibutton(app.Question2DiscreteTab, 'push');
            app.Q2_Button_DrawDisctrete.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q2_DrawDisctrete, true);
            app.Q2_Button_DrawDisctrete.Text = 'Draw';
            app.DiscreteFormula_Panel = uipanel(app.Question2DiscreteTab);
            app.DiscreteFormula_Panel.Title = 'Formula :';
            app.nLabel_2 = uilabel(app.DiscreteFormula_Panel);
            app.nLabel_2.Text = 'n';
            app.plusORminusDiscrete_DropDown = uidropdown(app.DiscreteFormula_Panel);
            app.plusORminusDiscrete_DropDown.Items = {'+', '-'};
            app.plusORminusDiscrete_DropDown.Value = '+';
            app.Label_36 = uilabel(app.DiscreteFormula_Panel);
            app.Label_36.Text = '(';
            app.Label_37 = uilabel(app.DiscreteFormula_Panel);
            app.Label_37.Text = ')    ,';
            app.Label_38 = uilabel(app.DiscreteFormula_Panel);
            app.Label_38.Text = '+';
            app.Label_39 = uilabel(app.DiscreteFormula_Panel);
            app.Label_39.Text = '_';
            app.AEditField_2Label = uilabel(app.DiscreteFormula_Panel);
            app.AEditField_2Label.Text = 'A';
            app.AmplitudeDiscrete_EditField = uieditfield(app.DiscreteFormula_Panel, 'numeric');
            app.AmplitudeDiscrete_EditField.Value = 1;
            app.EditField_7Label = uilabel(app.DiscreteFormula_Panel);
            app.EditField_7Label.Text = '𝟂';
            app.FrequencyDiscrete_EditField = uieditfield(app.DiscreteFormula_Panel, 'numeric');
            app.FrequencyDiscrete_EditField.Value = 1;
            app.EditField_8Label = uilabel(app.DiscreteFormula_Panel);
            app.EditField_8Label.Text = '𝞱';
            app.PhaseDiscrete_EditField = uieditfield(app.DiscreteFormula_Panel, 'numeric');
            app.sincosDiscrete_DropDown = uidropdown(app.DiscreteFormula_Panel);
            app.sincosDiscrete_DropDown.Items = {'sin', 'cos'};
            app.sincosDiscrete_DropDown.Value = 'sin';
            app.sincosDropDown_2Label = uilabel(app.DiscreteFormula_Panel);
            app.sincosDropDown_2Label.Text = 'sin || cos';
            app.Label_46 = uilabel(app.DiscreteFormula_Panel);
            app.Label_46.Text = '≤';
            app.nEnd_EditField = uieditfield(app.DiscreteFormula_Panel, 'numeric');
            app.nEnd_EditField.Limits = [-1000 1000];
            app.nEnd_EditField.Value = 10;
            app.Label_47 = uilabel(app.DiscreteFormula_Panel);
            app.Label_47.Text = '≤';
            app.nStart_EditField = uieditfield(app.DiscreteFormula_Panel, 'numeric');
            app.nStart_EditField.Limits = [-1000 1000];
            app.nStart_EditField.Value = -10;
            app.nLabel_3 = uilabel(app.DiscreteFormula_Panel);
            app.nLabel_3.Text = 'n';
            app.sincostDiscreteOriginal_Label = uilabel(app.Question2DiscreteTab);
            app.sincostDiscreteOriginal_Label.Text = '(sin | cos)(n)';
            app.sincostDiscreteAmplitude_Label = uilabel(app.Question2DiscreteTab);
            app.sincostDiscreteAmplitude_Label.Text = 'A (sin | cos)(n)';
            app.sincostDiscretePhase_Label = uilabel(app.Question2DiscreteTab);
            app.sincostDiscretePhase_Label.Text = '(sin | cos)(n(+|-)𝞱)';
            app.sincostDiscreteFraquency_Label = uilabel(app.Question2DiscreteTab);
            app.sincostDiscreteFraquency_Label.Text = '(sin | cos)(𝟂n)';
            app.Label_33 = uilabel(app.Question2DiscreteTab);
            app.Label_33.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_34 = uilabel(app.Question2DiscreteTab);
            app.Label_34.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_35 = uilabel(app.Question2DiscreteTab);
            app.Label_35.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.UIAxes_Discrete_ChangedAmplitude = uiaxes(app.Question2DiscreteTab);
            title(app.UIAxes_Discrete_ChangedAmplitude, 'Changed Amplitude (A)');
            xlabel(app.UIAxes_Discrete_ChangedAmplitude, 'n');
            ylabel(app.UIAxes_Discrete_ChangedAmplitude, 'x[n]');
            app.UIAxes_Discrete_ChangedAmplitude.XAxisLocation = 'origin';
            app.UIAxes_Discrete_ChangedAmplitude.YAxisLocation = 'origin';
            app.NpointsbetweenStartandEndIf0thenNStartEnd1Label = uilabel(app.Question2DiscreteTab);
            app.NpointsbetweenStartandEndIf0thenNStartEnd1Label.Text = {' N points between Start and End :'; ''; ' (If 0 then N = |Start |+|End|+1) '};
            app.NpointsbetweenStartandEndIf0thenNStartEnd1EditField = uieditfield(app.Question2DiscreteTab, 'numeric');
            app.NpointsbetweenStartandEndIf0thenNStartEnd1EditField.Limits = [0 10000];
            app.Question37Tab = uitab(app.TabGroup);
            app.Question37Tab.Title = 'Question 3 : 7';
            app.Question37Tab.Units = 'pixels';
            app.Panel_Question_7 = uipanel(app.Question37Tab);
            app.powern_Label = uilabel(app.Panel_Question_7);
            app.powern_Label.Text = 'n';
            app.xn14_Label = uilabel(app.Panel_Question_7);
            app.xn14_Label.Text = 'x[n] = (1/4)';
            app.Q7_Result_Label = uilabel(app.Panel_Question_7);
            app.Q7_Result_Label.Text = '........';
            app.Q7_Label = uilabel(app.Panel_Question_7);
            app.Q7_Label.Text = '7-';
            app.Q7_ZTransform_Button = uibutton(app.Panel_Question_7, 'push');
            app.Q7_ZTransform_Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q7_ZTransform, true);
            app.Q7_ZTransform_Button.Text = '=';
            app.Panel_Question_6 = uipanel(app.Question37Tab);
            app.hn_Label = uilabel(app.Panel_Question_6);
            app.hn_Label.Text = 'h[n] = ';
            app.xn_Label = uilabel(app.Panel_Question_6);
            app.xn_Label.Text = ',     x[n] = ';
            app.yn_Label = uilabel(app.Panel_Question_6);
            app.yn_Label.Text = 'y[n]  ';
            app.Q6_Result_Label = uilabel(app.Panel_Question_6);
            app.Q6_Result_Label.Text = '........';
            app.hn_EditField = uieditfield(app.Panel_Question_6, 'text');
            app.hn_EditField.Value = '2,1,3';
            app.xn_EditField = uieditfield(app.Panel_Question_6, 'text');
            app.xn_EditField.Value = '3,2,1';
            app.Q6_Convolution_Button = uibutton(app.Panel_Question_6, 'push');
            app.Q6_Convolution_Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q6_Convolution, true);
            app.Q6_Convolution_Button.Text = '=';
            app.EntervalidNUMBERSSEPARATEDeg123or123or123Label = uilabel(app.Panel_Question_6);
            app.EntervalidNUMBERSSEPARATEDeg123or123or123Label.Text = {'Enter valid NUMBERS SEPARATED'; '( e.g. 1,2,3 or 1 2 3 or [1 2 3])'};
            app.Q6_Label = uilabel(app.Panel_Question_6);
            app.Q6_Label.Text = '6-';
            app.Panel_Question_3 = uipanel(app.Question37Tab);
            app.kLabel = uilabel(app.Panel_Question_3);
            app.kLabel.Text = 'k';
            app.Braces = uilabel(app.Panel_Question_3);
            app.Braces.Text = '(       )';
            app.Label_Sigma_Symbol = uilabel(app.Panel_Question_3);
            app.Label_Sigma_Symbol.Text = 'Σ';
            app.Q3_Result_Label = uilabel(app.Panel_Question_3);
            app.Q3_Result_Label.Text = '........';
            app.Q3_Summations_Button = uibutton(app.Panel_Question_3, 'push');
            app.Q3_Summations_Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q3_Summations, true);
            app.Q3_Summations_Button.Text = '=';
            app.kLabel_2 = uilabel(app.Panel_Question_3);
            app.kLabel_2.Text = 'k =';
            app.kStart_EditField = uieditfield(app.Panel_Question_3, 'numeric');
            app.Value_EditField = uieditfield(app.Panel_Question_3, 'numeric');
            app.Value_EditField.Value = 0.5;
            app.kEnd_EditField = uieditfield(app.Panel_Question_3, 'numeric');
            app.kEnd_EditField.Value = Inf;
            app.Q3_Label = uilabel(app.Panel_Question_3);
            app.Q3_Label.Text = '3-';
            app.abLabel = uilabel(app.Panel_Question_3);
            app.abLabel.Text = '(a & b)';
            app.Panel_Question_4 = uipanel(app.Question37Tab);
            app.t_Label = uilabel(app.Panel_Question_4);
            app.t_Label.Text = 't';
            app.exp_Label = uilabel(app.Panel_Question_4);
            app.exp_Label.Text = 'ℯ';
            app.IntSymbol_Label = uilabel(app.Panel_Question_4);
            app.IntSymbol_Label.Text = '∫';
            app.Q4_Result_Label = uilabel(app.Panel_Question_4);
            app.Q4_Result_Label.Text = '........';
            app.Label_17 = uilabel(app.Panel_Question_4);
            app.Label_17.Text = '-∞';
            app.Label_18 = uilabel(app.Panel_Question_4);
            app.Label_18.Text = '0';
            app.dt_Label = uilabel(app.Panel_Question_4);
            app.dt_Label.Text = '.dt';
            app.Q4_Label = uilabel(app.Panel_Question_4);
            app.Q4_Label.Text = '4-';
            app.Q4_Integration_Button = uibutton(app.Panel_Question_4, 'push');
            app.Q4_Integration_Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q4_Integration, true);
            app.Q4_Integration_Button.Text = '=';
            app.Panel_Question_5 = uipanel(app.Question37Tab);
            app.power3_Label = uilabel(app.Panel_Question_5);
            app.power3_Label.Text = '3';
            app.yx_Label = uilabel(app.Panel_Question_5);
            app.yx_Label.Text = 'y = x     ,';
            app.dy_Label = uilabel(app.Panel_Question_5);
            app.dy_Label.Text = 'dy';
            app.dx_Label = uilabel(app.Panel_Question_5);
            app.dx_Label.Text = 'dx';
            app.over_Label = uilabel(app.Panel_Question_5);
            app.over_Label.Text = 'ــــــ';
            app.Q5_Result_Label = uilabel(app.Panel_Question_5);
            app.Q5_Result_Label.Text = '........';
            app.Q5_Label = uilabel(app.Panel_Question_5);
            app.Q5_Label.Text = '5-';
            app.Q5_Differentiation_Button = uibutton(app.Panel_Question_5, 'push');
            app.Q5_Differentiation_Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q5_Differentiation, true);
            app.Q5_Differentiation_Button.Text = '=';
            app.Label_40 = uilabel(app.Question37Tab);
            app.Label_40.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_41 = uilabel(app.Question37Tab);
            app.Label_41.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_42 = uilabel(app.Question37Tab);
            app.Label_42.Text = 'ـــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــــ';
            app.Label_43 = uilabel(app.Question37Tab);
            app.Label_43.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_49 = uilabel(app.Question37Tab);
            app.Label_49.Text = '';
            app.Question8FourierTransformTab = uitab(app.TabGroup);
            app.Question8FourierTransformTab.Title = 'Question 8 ( Fourier Transform )';
            app.Question8FourierTransformTab.Units = 'pixels';
            app.UIAxes5 = uiaxes(app.Question8FourierTransformTab);
            title(app.UIAxes5, 'Complex Frequency-domain');
            xlabel(app.UIAxes5, 'X');
            ylabel(app.UIAxes5, 'Y');
            app.Q8_Button = uibutton(app.Question8FourierTransformTab, 'push');
            app.Q8_Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Q8_FourierTransform, true);
            app.Q8_Button.Text = 'Draw';
            app.UIAxes5_2 = uiaxes(app.Question8FourierTransformTab);
            title(app.UIAxes5_2, 'Time-domain');
            xlabel(app.UIAxes5_2, 'X');
            ylabel(app.UIAxes5_2, 'Y');
            app.UIAxes6 = uiaxes(app.Question8FourierTransformTab);
            title(app.UIAxes6, 'Title');
            xlabel(app.UIAxes6, 'X');
            ylabel(app.UIAxes6, 'Y');
            app.SignalsProjectLabel = uilabel(app.SignalProjectGUIUIFigure);
            app.SignalsProjectLabel.Text = 'Signals Project';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SignalProjectGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalProjectGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalProjectGUIUIFigure)
        end
    end
end